classdef BonI3_GUI_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        BonI30GUILabel                  matlab.ui.control.Label
        ExcitationFrequencyHzKnobLabel  matlab.ui.control.Label
        ExcitationFrequencyHzKnob       matlab.ui.control.Knob
        Spinner_1                       matlab.ui.control.Spinner
        ConnectionModeButtonGroup       matlab.ui.container.ButtonGroup
        BluetoothButton                 matlab.ui.control.ToggleButton
        WiFiButton                      matlab.ui.control.ToggleButton
        SerialPortStateLamp             matlab.ui.control.Lamp
        Channel1SwitchLabel             matlab.ui.control.Label
        Channel1Switch                  matlab.ui.control.RockerSwitch
        AmplitudemVrmsSpinnerLabel      matlab.ui.control.Label
        AmplitudemVrmsSpinner_1         matlab.ui.control.Spinner
        Spinner_2                       matlab.ui.control.Spinner
        ExcitationFrequencyHzKnob_2Label  matlab.ui.control.Label
        ExcitationFrequencyHzKnob_2     matlab.ui.control.Knob
        Channel2SwitchLabel             matlab.ui.control.Label
        Channel2Switch                  matlab.ui.control.RockerSwitch
        AmplitudemVrmsSpinner_2Label    matlab.ui.control.Label
        AmplitudemVrmsSpinner_2         matlab.ui.control.Spinner
        Spinner_3                       matlab.ui.control.Spinner
        ExcitationFrequencyHzKnob_3Label  matlab.ui.control.Label
        ExcitationFrequencyHzKnob_3     matlab.ui.control.Knob
        Channel3SwitchLabel             matlab.ui.control.Label
        Channel3Switch                  matlab.ui.control.RockerSwitch
        AmplitudemVrmsSpinner_3Label    matlab.ui.control.Label
        AmplitudemVrmsSpinner_3         matlab.ui.control.Spinner
        SerialPortDropDownLabel         matlab.ui.control.Label
        SerialPortDropDown              matlab.ui.control.DropDown
        OpenSerialPortButton            matlab.ui.control.Button
        Channel1StateLabel              matlab.ui.control.Label
        Channel3StateLabel              matlab.ui.control.Label
        Channel2StateLabel              matlab.ui.control.Label
        Lamp_2                          matlab.ui.control.Lamp
        Lamp_3                          matlab.ui.control.Lamp
        Lamp_4                          matlab.ui.control.Lamp
        SendButton                      matlab.ui.control.Button
        AmplitudeSlider_1               matlab.ui.control.Slider
        AmplitudeSlider_2               matlab.ui.control.Slider
        AmplitudeSlider_3               matlab.ui.control.Slider
    end

    
    properties (Access = public)
        s;
        COM;        
        RX_num; 
        TX_num; 
        RX_once;
        RX_Date;
        is_open;
        freq1;
        amp1;
        freq2;
        amp2;
        freq3;
        amp3;
%         open_1;
%         state_1;
%         Fre_2;
%         Amp_2;
%         open_2;
%         state_2;
%         Fre_3;
%         Amp_3;
%         open_3;
%         state_3;
    end
    
%     methods (Access = public)
%         function  EveBytesAvailableFcn(app, src, event)
%             n = src.BytesAvailable;%Get the number of characters received
%             if n>0%n>0 to continue execution, because 0 is 0 will also trigger an interrupt
%                 app.RX_num=app.RX_num+n;
%                 app.Label_RX.Text=num2str(app.RX_num);%Convert numbers to string output
%                 app.RX_once=fread(src,n,'uchar');%Read function, the matrix is a column after reading
%                 app.RX_Date =strcat(app.RX_Date, app.RX_once');%String splicing, need to be transposed into one line
%                 app.ReceiveView.Value= app.RX_Date;
%                 
%             end
%         end
%     end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RX_num=0;
            app.TX_num=0;
            app.is_open=0;
            scoms = instrfind;%获取占用的串口号
            if scoms ~= 0%如果存在则关闭，否则不能打开
                stopasync(scoms);
                fclose(scoms);
                delete(scoms);
            end            
        end

        % Value changed function: ExcitationFrequencyHzKnob
        function ExcitationFrequencyHzKnobValueChanged(app, event)
            app.freq1 = app.ExcitationFrequencyHzKnob.Value;
            app.Spinner_1.Value = app.freq1;
            freq = int32(app.freq1);
            amp = int32(app.amp1);             
            switch app.Channel1Switch.Value
                case 'On'
                BTcall(1,amp,freq,0,app.s);
                app.Lamp_2.Color=[0 1 0];
                case 'off'
                app.Lamp_2.Color=[0.64 0.08 0.18];    
            end
                            
            
        end

        % Value changed function: Spinner_1
        function Spinner_1ValueChanged(app, event)
            app.freq1 = app.Spinner_1.Value;
            app.ExcitationFrequencyHzKnob.Value = app.freq1;
            freq = int32(app.freq1);
            amp = int32(app.amp1);            
            switch app.Channel1Switch.Value
                case 'On'
                BTcall(1,amp,freq,0,app.s);
                app.Lamp_2.Color=[0 1 0];
                case 'off'
                app.Lamp_2.Color=[0.64 0.08 0.18];                
            end
        end

        % Value changed function: ExcitationFrequencyHzKnob_2
        function ExcitationFrequencyHzKnob_2ValueChanged(app, event)
            app.freq2 = app.ExcitationFrequencyHzKnob_2.Value;
            app.Spinner_2.Value = app.freq2;
            freq = int32(app.freq2);
            amp = int32(app.amp2);
            switch app.Channel2Switch.Value
                case 'On'
                BTcall(2,amp,freq,0,app.s);
                app.Lamp_3.Color=[0 1 0];                
                case 'off'
                app.Lamp_3.Color=[0.64 0.08 0.18];
            end
        end

        % Value changed function: Spinner_2
        function Spinner_2ValueChanged(app, event)
            app.freq2 = app.Spinner_2.Value;
            app.ExcitationFrequencyHzKnob_2.Value = app.freq2;
            freq = int32(app.freq2);
            amp = int32(app.amp2);            
            switch app.Channel2Switch.Value
                case 'On'
                BTcall(2,amp,freq,0,app.s);
                app.Lamp_3.Color=[0 1 0];                
                case 'off'
                app.Lamp_3.Color=[0.64 0.08 0.18];
            end
        end

        % Value changed function: ExcitationFrequencyHzKnob_3
        function ExcitationFrequencyHzKnob_3ValueChanged(app, event)
            app.freq3 = app.ExcitationFrequencyHzKnob_3.Value;
            app.Spinner_3.Value = app.freq3;
            freq = int32(app.freq3);
            amp = int32(app.amp3);             
            switch app.Channel3Switch.Value
                case 'On'
                BTcall(3,amp,freq,0,app.s);
                app.Lamp_4.Color=[0 1 0];                
                case 'off'
                app.Lamp_4.Color=[0.64 0.08 0.18];                
            end
        end

        % Value changed function: Spinner_3
        function Spinner_3ValueChanged(app, event)
            app.freq3 = app.Spinner_3.Value;
            app.ExcitationFrequencyHzKnob_3.Value = app.freq3;
            freq = int32(app.freq3);
            amp = int32(app.amp3);             
            switch app.Channel3Switch.Value
                case 'On'
                BTcall(3,amp,freq,0,app.s);
                app.Lamp_4.Color=[0 1 0];                
                case 'off'
                app.Lamp_4.Color=[0.64 0.08 0.18];
            end
        end

        % Button pushed function: OpenSerialPortButton
        function OpenSerialPortButtonPushed(app, event)
            
            app.COM=get(app.SerialPortDropDown,'Value');
            if strcmp(get(app.OpenSerialPortButton,'Text'),'Open Serial Port')
                try
                    app.s=serialport(app.COM,115200,"DataBits",8,"StopBits",1);
%                     app.s.BytesAvailableFcnMode='byte'; %Serial port event callback settings
%                     app.s.Terminator='CR/LF';
%                     app.s.BytesAvailableFcnCount=1; %input buffer to trigger the callback function
%                     app.s.BytesAvailableFcn={@app.EveBytesAvailableFcn};%the callback function
                    app.is_open=1;
                    app.OpenSerialPortButton.Text='Close Serial Port';
                    app.SerialPortStateLamp.Color=[0 1 0];          
                catch err
                    msgbox('Failed to open');
                end
            else
                try
                    delete(app.s);
                    app.OpenSerialPortButton.Text='Open Serial Port';
                    app.SerialPortStateLamp.Color=[0.64 0.08 0.18];
                catch err
                    msgbox('Failed to close');
                end
                app.is_open=0;
            end            
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            freq_1 = int32(app.freq1);
            amp_1 = int32(app.amp1);             
            switch app.Channel1Switch.Value
                case 'On'
                BTcall(1,amp_1,freq_1,0,app.s);   
            end
            freq_2 = int32(app.freq2);
            amp_2 = int32(app.amp2);             
            switch app.Channel1Switch.Value
                case 'On'
                BTcall(2,amp_2,freq_2,0,app.s);   
            end
            freq_3 = int32(app.freq3);
            amp_3 = int32(app.amp3);             
            switch app.Channel1Switch.Value
                case 'On'
                BTcall(3,amp_3,freq_3,0,app.s);   
            end               
        end

        % Value changed function: Channel1Switch
        function Channel1SwitchValueChanged(app, event)
            value = app.Channel1Switch.Value;
            switch value
                case 'On'
                BTcall(0,1,2,2,app.s);
                app.Lamp_2.Color=[0 1 0];                
                case 'Off'
                BTcall(0,0,2,2,app.s); 
                app.Lamp_2.Color=[0.64 0.08 0.18];                
            end
        end

        % Value changed function: Channel2Switch
        function Channel2SwitchValueChanged(app, event)
            value = app.Channel2Switch.Value;
            switch value
                case 'On'                    
                BTcall(0,2,1,2,app.s);
                app.Lamp_3.Color=[0 1 0];
                case 'Off'
                BTcall(0,2,0,2,app.s);
                app.Lamp_3.Color=[0.64 0.08 0.18];
            end
        end

        % Value changed function: Channel3Switch
        function Channel3SwitchValueChanged(app, event)
            value = app.Channel3Switch.Value;
            switch value
                case 'On'
                BTcall(0,2,2,1,app.s);
                app.Lamp_4.Color=[0 1 0];                
                case 'Off'
                BTcall(0,2,1,0,app.s); 
                app.Lamp_4.Color=[0.64 0.08 0.18];                
            end
        end

        % Value changed function: AmplitudemVrmsSpinner_1
        function AmplitudemVrmsSpinner_1ValueChanged(app, event)
            app.amp1 = app.AmplitudemVrmsSpinner_1.Value;
            app.AmplitudeSlider_1.Value = app.amp1;
            freq = int32(app.freq1);
            amp = int32(app.amp1);            
            switch app.Channel1Switch.Value
                case 'On'
                BTcall(1,amp,freq,0,app.s);
                app.Lamp_2.Color=[0 1 0];                
                case 'off'
                app.Lamp_2.Color=[0.64 0.08 0.18];                
            end
        end

        % Value changed function: AmplitudemVrmsSpinner_2
        function AmplitudemVrmsSpinner_2ValueChanged(app, event)
            app.amp2 = app.AmplitudemVrmsSpinner_2.Value;
            app.AmplitudeSlider_2.Value = app.amp2;
            freq = int32(app.freq2);
            amp = int32(app.amp2);             
            switch app.Channel2Switch.Value
                case 'On'
                BTcall(2,amp,freq,0,app.s);
                app.Lamp_3.Color=[0 1 0];                
                case 'off'
                app.Lamp_3.Color=[0.64 0.08 0.18];                 
            end
        end

        % Value changed function: AmplitudemVrmsSpinner_3
        function AmplitudemVrmsSpinner_3ValueChanged(app, event)
            app.amp3 = app.AmplitudemVrmsSpinner_3.Value;
            app.AmplitudeSlider_3.Value = app.amp3;
            freq = int32(app.freq3);
            amp = int32(app.amp3);              
            switch app.Channel3Switch.Value
                case 'On'
                BTcall(3,amp,freq,0,app.s);
                app.Lamp_3.Color=[0 1 0];                
                case 'off'
                app.Lamp_3.Color=[0.64 0.08 0.18];      
            end
        end

        % Value changed function: AmplitudeSlider_1
        function AmplitudeSlider_1ValueChanged(app, event)
            app.amp1 = app.AmplitudeSlider_1.Value;
            app.AmplitudemVrmsSpinner_1.Value = app.amp1;
            freq = int32(app.freq1);
            amp = int32(app.amp1);            
            switch app.Channel1Switch.Value
                case 'On'
                BTcall(1,amp,freq,0,app.s);
                app.Lamp_3.Color=[0 1 0];                
                case 'off'
                app.Lamp_3.Color=[0.64 0.08 0.18];                  
            end
        end

        % Value changed function: AmplitudeSlider_2
        function AmplitudeSlider_2ValueChanged(app, event)
            app.amp2 = app.AmplitudeSlider_2.Value;
            app.AmplitudemVrmsSpinner_2.Value = app.amp2;
            freq = int32(app.freq2);
            amp = int32(app.amp2);              
            switch app.Channel2Switch.Value
                case 'On'
                BTcall(2,amp,freq,0,app.s);
                app.Lamp_2.Color=[0 1 0];                
                case 'off'
                app.Lamp_2.Color=[0.64 0.08 0.18];                 
            end
        end

        % Value changed function: AmplitudeSlider_3
        function AmplitudeSlider_3ValueChanged(app, event)
            app.amp3 = app.AmplitudeSlider_3.Value;
            app.AmplitudemVrmsSpinner_3.Value = app.amp3;
            freq = int32(app.freq3);
            amp = int32(app.amp3);              
            switch app.Channel3Switch.Value
                case 'On'
                BTcall(3,amp,freq,0,app.s);
                app.Lamp_4.Color=[0 1 0];                
                case 'off'
                app.Lamp_4.Color=[0.64 0.08 0.18];       
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BonI30GUILabel = uilabel(app.UIFigure);
            app.BonI30GUILabel.Text = 'BonI 3.0 GUI ';
            app.ExcitationFrequencyHzKnobLabel = uilabel(app.UIFigure);
            app.ExcitationFrequencyHzKnobLabel.Text = 'Excitation Frequency (Hz)';
            app.ExcitationFrequencyHzKnob = uiknob(app.UIFigure, 'continuous');
            app.ExcitationFrequencyHzKnob.Limits = [100 6300];
            app.ExcitationFrequencyHzKnob.ValueChangedFcn = createCallbackFcn(app, @ExcitationFrequencyHzKnobValueChanged, true);
            app.ExcitationFrequencyHzKnob.Value = 100;
            app.Spinner_1 = uispinner(app.UIFigure);
            app.Spinner_1.Limits = [0 6300];
            app.Spinner_1.RoundFractionalValues = 'on';
            app.Spinner_1.ValueChangedFcn = createCallbackFcn(app, @Spinner_1ValueChanged, true);
            app.Spinner_1.Value = 100;
            app.ConnectionModeButtonGroup = uibuttongroup(app.UIFigure);
            app.ConnectionModeButtonGroup.Title = 'Connection Mode';
            app.BluetoothButton = uitogglebutton(app.ConnectionModeButtonGroup);
            app.BluetoothButton.Text = 'Bluetooth';
            app.BluetoothButton.Value = true;
            app.WiFiButton = uitogglebutton(app.ConnectionModeButtonGroup);
            app.WiFiButton.Text = 'WiFi';
            app.SerialPortStateLamp = uilamp(app.UIFigure);
            app.Channel1SwitchLabel = uilabel(app.UIFigure);
            app.Channel1SwitchLabel.Text = 'Channel 1';
            app.Channel1Switch = uiswitch(app.UIFigure, 'rocker');
            app.Channel1Switch.ValueChangedFcn = createCallbackFcn(app, @Channel1SwitchValueChanged, true);
            app.Channel1Switch.Value = 'On';
            app.AmplitudemVrmsSpinnerLabel = uilabel(app.UIFigure);
            app.AmplitudemVrmsSpinnerLabel.Text = 'Amplitude (mVrms)';
            app.AmplitudemVrmsSpinner_1 = uispinner(app.UIFigure);
            app.AmplitudemVrmsSpinner_1.Limits = [0 2000];
            app.AmplitudemVrmsSpinner_1.RoundFractionalValues = 'on';
            app.AmplitudemVrmsSpinner_1.ValueChangedFcn = createCallbackFcn(app, @AmplitudemVrmsSpinner_1ValueChanged, true);
            app.Spinner_2 = uispinner(app.UIFigure);
            app.Spinner_2.Limits = [0 6300];
            app.Spinner_2.RoundFractionalValues = 'on';
            app.Spinner_2.ValueChangedFcn = createCallbackFcn(app, @Spinner_2ValueChanged, true);
            app.Spinner_2.Value = 100;
            app.ExcitationFrequencyHzKnob_2Label = uilabel(app.UIFigure);
            app.ExcitationFrequencyHzKnob_2Label.Text = 'Excitation Frequency (Hz)';
            app.ExcitationFrequencyHzKnob_2 = uiknob(app.UIFigure, 'continuous');
            app.ExcitationFrequencyHzKnob_2.Limits = [100 6300];
            app.ExcitationFrequencyHzKnob_2.ValueChangedFcn = createCallbackFcn(app, @ExcitationFrequencyHzKnob_2ValueChanged, true);
            app.ExcitationFrequencyHzKnob_2.Value = 100;
            app.Channel2SwitchLabel = uilabel(app.UIFigure);
            app.Channel2SwitchLabel.Text = 'Channel 2';
            app.Channel2Switch = uiswitch(app.UIFigure, 'rocker');
            app.Channel2Switch.ValueChangedFcn = createCallbackFcn(app, @Channel2SwitchValueChanged, true);
            app.AmplitudemVrmsSpinner_2Label = uilabel(app.UIFigure);
            app.AmplitudemVrmsSpinner_2Label.Text = 'Amplitude (mVrms)';
            app.AmplitudemVrmsSpinner_2 = uispinner(app.UIFigure);
            app.AmplitudemVrmsSpinner_2.Limits = [0 2830];
            app.AmplitudemVrmsSpinner_2.RoundFractionalValues = 'on';
            app.AmplitudemVrmsSpinner_2.ValueChangedFcn = createCallbackFcn(app, @AmplitudemVrmsSpinner_2ValueChanged, true);
            app.Spinner_3 = uispinner(app.UIFigure);
            app.Spinner_3.Limits = [0 6300];
            app.Spinner_3.RoundFractionalValues = 'on';
            app.Spinner_3.ValueChangedFcn = createCallbackFcn(app, @Spinner_3ValueChanged, true);
            app.Spinner_3.Value = 100;
            app.ExcitationFrequencyHzKnob_3Label = uilabel(app.UIFigure);
            app.ExcitationFrequencyHzKnob_3Label.Text = 'Excitation Frequency (Hz)';
            app.ExcitationFrequencyHzKnob_3 = uiknob(app.UIFigure, 'continuous');
            app.ExcitationFrequencyHzKnob_3.Limits = [100 6300];
            app.ExcitationFrequencyHzKnob_3.ValueChangedFcn = createCallbackFcn(app, @ExcitationFrequencyHzKnob_3ValueChanged, true);
            app.ExcitationFrequencyHzKnob_3.Value = 100;
            app.Channel3SwitchLabel = uilabel(app.UIFigure);
            app.Channel3SwitchLabel.Text = 'Channel 3';
            app.Channel3Switch = uiswitch(app.UIFigure, 'rocker');
            app.Channel3Switch.ValueChangedFcn = createCallbackFcn(app, @Channel3SwitchValueChanged, true);
            app.AmplitudemVrmsSpinner_3Label = uilabel(app.UIFigure);
            app.AmplitudemVrmsSpinner_3Label.Text = 'Amplitude (mVrms)';
            app.AmplitudemVrmsSpinner_3 = uispinner(app.UIFigure);
            app.AmplitudemVrmsSpinner_3.Limits = [0 2830];
            app.AmplitudemVrmsSpinner_3.RoundFractionalValues = 'on';
            app.AmplitudemVrmsSpinner_3.ValueChangedFcn = createCallbackFcn(app, @AmplitudemVrmsSpinner_3ValueChanged, true);
            app.SerialPortDropDownLabel = uilabel(app.UIFigure);
            app.SerialPortDropDownLabel.Text = 'Serial Port';
            app.SerialPortDropDown = uidropdown(app.UIFigure);
            app.SerialPortDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9', 'COM10', 'COM11', 'COM12'};
            app.SerialPortDropDown.Value = 'COM5';
            app.OpenSerialPortButton = uibutton(app.UIFigure, 'push');
            app.OpenSerialPortButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSerialPortButtonPushed, true);
            app.OpenSerialPortButton.Text = 'Open Serial Port';
            app.Channel1StateLabel = uilabel(app.UIFigure);
            app.Channel1StateLabel.Text = 'Channel 1 State';
            app.Channel3StateLabel = uilabel(app.UIFigure);
            app.Channel3StateLabel.Text = 'Channel 3 State';
            app.Channel2StateLabel = uilabel(app.UIFigure);
            app.Channel2StateLabel.Text = 'Channel 2 State';
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_3 = uilamp(app.UIFigure);
            app.Lamp_4 = uilamp(app.UIFigure);
            app.SendButton = uibutton(app.UIFigure, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.AmplitudeSlider_1 = uislider(app.UIFigure);
            app.AmplitudeSlider_1.Limits = [0 2000];
            app.AmplitudeSlider_1.MajorTicks = [0 500 1000 1500 2000];
            app.AmplitudeSlider_1.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSlider_1ValueChanged, true);
            app.AmplitudeSlider_2 = uislider(app.UIFigure);
            app.AmplitudeSlider_2.Limits = [0 2830];
            app.AmplitudeSlider_2.MajorTicks = [0 700 1400 2100 2830];
            app.AmplitudeSlider_2.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSlider_2ValueChanged, true);
            app.AmplitudeSlider_3 = uislider(app.UIFigure);
            app.AmplitudeSlider_3.Limits = [0 2830];
            app.AmplitudeSlider_3.MajorTicks = [0 700 1400 2100 2830];
            app.AmplitudeSlider_3.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSlider_3ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BonI3_GUI_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end